classdef Robo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        HaubtGrid                  matlab.ui.container.GridLayout
        Anzeige                    matlab.ui.container.Panel
        AnzeigenGrid               matlab.ui.container.GridLayout
        Robotarm                   matlab.ui.control.UIAxes
        theta1verlauf              matlab.ui.control.UIAxes
        theta2verlauf              matlab.ui.control.UIAxes
        StartwerPanel              matlab.ui.container.Panel
        StartwertGrid              matlab.ui.container.GridLayout
        p_yEditField_4Label_2      matlab.ui.control.Label
        p_y                        matlab.ui.control.NumericEditField
        p_xEditField_4Label_2      matlab.ui.control.Label
        p_x                        matlab.ui.control.NumericEditField
        theta_2EditField_4Label_2  matlab.ui.control.Label
        theta_2                    matlab.ui.control.NumericEditField
        theta_1EditField_4Label_2  matlab.ui.control.Label
        theta_1                    matlab.ui.control.NumericEditField
        infotext_6                 matlab.ui.control.Label
        infotext_5                 matlab.ui.control.Label
        vorwaerts                  matlab.ui.control.Button
        ruckwaerts                 matlab.ui.control.Button
        Helpbutton_1               matlab.ui.control.Button
        SettingsPanel              matlab.ui.container.TabGroup
        Runtime                    matlab.ui.container.Tab
        RuntimeGrid                matlab.ui.container.GridLayout
        Arbeitsbereich             matlab.ui.control.StateButton
        Tempolabel                 matlab.ui.control.Label
        TempoSlider                matlab.ui.control.Slider
        Clear                      matlab.ui.control.Button
        Startrobot                 matlab.ui.control.StateButton
        Lamp                       matlab.ui.control.Lamp
        Vefahrensgruppe            matlab.ui.container.ButtonGroup
        Nummerisch                 matlab.ui.control.RadioButton
        Analytisch                 matlab.ui.control.RadioButton
        Planung                    matlab.ui.control.Button
        Verfahren                  matlab.ui.control.Button
        Statuspercentlabel         matlab.ui.control.Label
        Helpbutton_2               matlab.ui.control.Button
        Armsettings                matlab.ui.container.Tab
        ArmsettingGrid             matlab.ui.container.GridLayout
        m1Label                    matlab.ui.control.Label
        m1                         matlab.ui.control.NumericEditField
        m2Label                    matlab.ui.control.Label
        m2                         matlab.ui.control.NumericEditField
        L2Label                    matlab.ui.control.Label
        L2                         matlab.ui.control.NumericEditField
        Lc2Label                   matlab.ui.control.Label
        Lc2                        matlab.ui.control.NumericEditField
        I2Label                    matlab.ui.control.Label
        I2                         matlab.ui.control.NumericEditField
        I1Label                    matlab.ui.control.Label
        I1                         matlab.ui.control.NumericEditField
        Kr2Label                   matlab.ui.control.Label
        Kr2                        matlab.ui.control.NumericEditField
        L1                         matlab.ui.control.NumericEditField
        L1Label                    matlab.ui.control.Label
        G                          matlab.ui.control.NumericEditField
        GLabel                     matlab.ui.control.Label
        Lc1                        matlab.ui.control.NumericEditField
        Lc1Label                   matlab.ui.control.Label
        Kr1                        matlab.ui.control.NumericEditField
        Kr1Label                   matlab.ui.control.Label
        Set                        matlab.ui.control.Button
        Reset                      matlab.ui.control.Button
        Helpbutton_4               matlab.ui.control.Button
        ZielwertePanel             matlab.ui.container.Panel
        GridLayout6_2              matlab.ui.container.GridLayout
        p_yEditField_4Label        matlab.ui.control.Label
        p_y_2                      matlab.ui.control.NumericEditField
        p_xEditField_4Label        matlab.ui.control.Label
        p_x_2                      matlab.ui.control.NumericEditField
        theta_2EditField_4Label    matlab.ui.control.Label
        theta_2_2                  matlab.ui.control.NumericEditField
        theta_1EditField_4Label    matlab.ui.control.Label
        theta_1_2                  matlab.ui.control.NumericEditField
        infotext_1                 matlab.ui.control.Label
        infotext_2                 matlab.ui.control.Label
        vorwaerts_2                matlab.ui.control.Button
        ruckwaerts_2               matlab.ui.control.Button
        Helpbutton_3               matlab.ui.control.Button
        Hinweis                    matlab.ui.container.Panel
        HinweisGrid                matlab.ui.container.GridLayout
        InfoText                   matlab.ui.control.Label
    end


    properties (Access = public)
        
        % variablen für ergebnisse zum darstellen
        theta1soll=0;
        theta2soll=0;
        theta1ist=0;
        theta2ist=0;
    end

    methods (Access = private)
        
        function drawtheta(app,string)
            
            if string == "planung"
                
                % zeichnet winkel in die winkel plots ein
                plot( app.theta1verlauf, app.theta1soll(:,1), app.theta1soll(:,2) );
                plot( app.theta2verlauf, app.theta2soll(:,1), app.theta2soll(:,2) );
                app.theta1verlauf.XLim = [0, 10];
                app.theta2verlauf.XLim = [0, 10];
                
            elseif string == "verlauf"
                
                % zeichnet winkel in die winkel plots ein
                hold(app.theta1verlauf, "on");
                plot(app.theta1verlauf,app.theta1ist.time,app.theta1ist.data);
                grid(app.theta1verlauf, "on");
                hold(app.theta1verlauf, "off");
    
                hold(app.theta2verlauf, "on");
                plot(app.theta2verlauf,app.theta2ist.time,app.theta2ist.data);
                grid(app.theta2verlauf, "on");
                hold(app.theta2verlauf, "off");
                
            else
                
                % ansonsten leert es beide plots
                app.theta1soll=0;
                app.theta2soll=0;
                app.theta1ist=0;
                app.theta2ist=0;
                cla(app.theta1verlauf);
                cla(app.theta2verlauf);
                
            end
        end
        
        function arbeitsbereich(~,ax)
            
            % berechnet für jeden punkt ob diesr im arbeitsbereich ist
            time = -1:0.02:1;
            p = zeros(length(time),length(time));
            for x = 1:length(time)
                for y = 1:length(time)
                    xa=time(x);
                    ya=time(y);
                    p(x,y) = fun_checkWorkspace(xa,ya);% prüft alle x + y positionen
                end
            end

            % fügt alle makierung in den plot ein
            hold(ax,"on") 
            for i=1:length(time)
                p(p==0) = NaN; % entfernt alle 0 so das sie nicht angzeigt werden
                plot(ax,time,time(i)*p(i,:),"b.",'MarkerSize',1)
            end
            hold(ax,"off")
        end
        
        function infotext(app,code)
            
            % infozentrale , gibt endnutzer info über nutzung und verlauf des programms
            if code == 0 % error
                app.InfoText.Text = sprintf("Error:\n Etwas lief schief ,  bitte starten sie die anwendung neu!");

            elseif code == 101 % 10x Infos
                app.InfoText.Text = sprintf("Good 2 Know:\n Beim Drücken des Plannungs und Verfahren buttons sind nur die werte unter P_x P_y relevant \n also winkel immer erst umrechnen lassen!");

            elseif code == 102 % 10x Infos
                app.InfoText.Text = sprintf("Good 2 Know:\n Wir Supporten Offizel Vollbild versuchen sie es!");

            elseif code == 103 % 10x Infos
                app.InfoText.Text = sprintf("Good 2 Know:\n Sie können das Tempo Während der Roboterbewegung live verändern \n oder die ganze bewegung cancel indem sie nocheinmal auf den Roboter bewegen button drücken");

            elseif code == 104 % 10x Infos
                app.InfoText.Text = sprintf("Good 2 Know:\n Stell immer sicher das du einen der pfeile drückst (links oder rechts)\n den das programm nutzt die theta und xy werte die derzeit im feld sind!");

            elseif code == 201 % 20x Warnungen
                app.InfoText.Text = sprintf("Warnung:\n px oder py werte sind auserhalb des arbeitsbereiches\n");

            elseif code == 301 % button texte
                app.InfoText.Text = sprintf("hier können die start werte des armes angeben werden. \n Das ändern der px und py werte , wird ebenfalls im Haubtplot dargestellt \n Winkeländerung haben nur eine auswirkung nachdem klicken des pfeiles der nach rechts zeigt ");
                
            elseif code == 302 % button texte
                app.InfoText.Text = sprintf("hier können die start werte des armes angeben werden. \n Das ändern der px und py werte , wird hier als punkt im Haubtplot dargestellt \n Winkeländerung haben nur eine auswirkung nachdem klicken des pfeiles der nach rechts zeigt ");
            
            elseif code == 303 % button texte
                app.InfoText.Text = sprintf("hier können einstellung bezüglich einer bewegung des armes getroffen werden \n Die verfahren funktion jedoch gehört noch zu den Start und Zielwerten und bestimmt wie die px/py werte in winkel umgerechnet werden!");
                
            elseif code == 304 % button texte
                app.InfoText.Text = sprintf("hier können einstellungen zu den roboterarmen verändert werden , um beispielsweiße eine funktion bei 50x fallgeschwindigkeit zu testen. Der Reset button setzt die werte nicht also immer drücken");

            elseif code == 500 % zufäliger info text
                random = randperm(4);
                infotext(app,(100+random(1)));
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % setzt standart werte
            reset_callback(app);
            set_werte_callback(app); % speichert gesetzte werte zum nutzen
            clear_callback(app);

            % führt einen beispielsnutzung aus um nutzern einen überblick zu gewähren
            app.drawrobo_callback; % zeichnet Roboterarm
            app.plannung_callback; % plant bewegung
            app.moverobo_callback; % führt bewegung aus
            infotext(app,500); % zufälliger info text
            
        end

        % Button pushed function: vorwaerts, vorwaerts_2
        function vorwaertskinematic_callback(app, event)
            
            if event.Source.Parent.Parent.Tag == "Startwert"
                
                [px,py] = fun_callForwardKin(app.theta_1.Value,app.theta_2.Value); % berechnet werte
                app.p_x.Value = round(px,3); % rundet ergebnisse auf 3 stellen und tut sie in die entsprechenden felder
                app.p_y.Value = round(py,3);
                
            elseif event.Source.Parent.Parent.Tag == "Zielwert"
                    
                [px,py] = fun_callForwardKin(app.theta_1_2.Value,app.theta_2_2.Value); % berechnet werte
                app.p_x_2.Value = round(px,3); % rundet ergebnisse auf 3 stellen und tut sie in die entsprechenden felder
                app.p_y_2.Value = round(py,3);
                
            end
            
            app.drawrobo_callback; % zeichnet Roboterarm
        end

        % Button pushed function: ruckwaerts, ruckwaerts_2
        function rueckwaertskinematic_callback(app, event)
            
            if event.Source.Parent.Parent.Tag == "Startwert"
                xvalue = app.p_x.Value;
                yvalue = app.p_y.Value;
            elseif event.Source.Parent.Parent.Tag == "Zielwert"
                xvalue = app.p_x_2.Value;
                yvalue = app.p_y_2.Value;
            end
            
            % prüft ob wert sich im arbeitsbereich befindet
            if fun_checkWorkspace(xvalue,yvalue) == 0
                infotext(app,201); % warnung
                return;
            else
                infotext(app,500); % info text
            end
            
            set_werte_callback(app); % speichert gesetzte werte zum nutzen
            % entscheidet ob das analytische oder nummerische verfahren
            % benutzt werden soll
            if app.Analytisch.Value == true
                [theta1,theta2] = fun_callInvKin(xvalue,yvalue);
            else
                [theta1,theta2] = fun_callInvKin_Num(xvalue,yvalue);
            end

            % speichert die werte in die enstprechenden felder und rundet
            % auf 3 stellen
            if event.Source.Parent.Parent.Tag == "Startwert"
                app.theta_1.Value = round(theta1,3);
                app.theta_2.Value = round(theta2,3);
            elseif event.Source.Parent.Parent.Tag == "Zielwert"
                app.theta_1_2.Value = round(theta1,3);
                app.theta_2_2.Value = round(theta2,3);
            end
            
            app.drawrobo_callback; % zeichnet Roboterarm
            
        end

        % Button pushed function: Planung
        function plannung_callback(app, event)

            % prüft ob wert sich im arbeitsbereich befindet
            if fun_checkWorkspace(app.p_x_2.Value,app.p_y_2.Value) == 0
                infotext(app,201); % warnung
                return;
            else
                infotext(app,500); % info text
            end
            
            drawtheta(app,"clear");% löscht wenn vorhanden alte werte
            [app.theta1soll,app.theta2soll] = fun_callTraj(app.p_x.Value,app.p_y.Value,app.p_x_2.Value,app.p_y_2.Value); % berechnet Winkelverlauf
            drawtheta(app,"planung"); % zeichnet verlauf
            
        end

        % Button pushed function: Verfahren
        function moverobo_callback(app, event)
            
            % speichert startwerte für roboter movement
            global th1 th2 th1p1 th2p1;
                th1p1 = 0;
                th2p1 = 0;
                th1 = app.theta_1.Value;
                th2 = app.theta_2.Value;
            
            plannung_callback(app); % da die werte von plannung benötigt werden führt das programm die plannung einfach selbst durch um fehler zu mitigrieren
            [app.theta1ist,app.theta2ist] = fun_moveRobo(app.theta1soll,app.theta2soll); % Berechnet winkelverlauf über symulink
            drawtheta(app,"verlauf"); % zeichnet verlauf
                
        end

        % Button pushed function: Clear
        function clear_callback(app, event)
           
            % setzt  alle werte auf 0
            % und leert die plots
            app.p_x.Value = app.L1.Value+app.L2.Value; % 0 grad
            app.p_y.Value = 0;
            app.p_x_2.Value = 0;
            app.p_y_2.Value = app.L1.Value+app.L2.Value; % 90 grad
            
            app.theta_1.Value = 0; % 0 grad
            app.theta_2.Value = 0;
            app.theta_1_2.Value = 90; % 90 grad
            app.theta_2_2.Value = 0;
            
            drawtheta(app,"clear");
            app.drawrobo_callback; % zeichnet verlauf
            
        end

        % Value changed function: Startrobot
        function start_bewegung_callback(app, event)
           % checkt ob verfahren schon benutzt wurde
            if app.theta1ist == 0 
                app.Startrobot.Value = false;
                return;
            end
            
            % funktion aus Verfahren ausgelagert , weil das neu zeichnen
            % des Roboter arms ältere Computer / Laptops belasten kann
            app.Lamp.Color = [1 0 0]; % lampe rot

            % berechnet eine zahl die bestimmt in wie großen schritten
            % gegangen wird wichtig da das verfahren ein array mit 100000
            % einheiten produzieren kann , jeder schritt wird sonst gezeichnet
            % und 50 ms gewartet
            len = min(length(app.theta1ist.time),length(app.theta2ist.time));
            i = 1;
            
            while i < len
                if app.Startrobot.Value == false % ermöglicht die bewegung zu beenden
                    break;
                end
                
                fun_drawRobo(app.Robotarm,app.theta1ist.data(i),app.theta2ist.data(i) ); % zeichnet den jeweiligen schritt
                app.Statuspercentlabel.Text = sprintf('%.2f',(i/len)*100) + "%"; % zeigt fortschrit in gerundet xxx.xx% an
                pause(50/1000); % wartet 50 ms bei 0 könnte der computer freezen
                i = i+round(app.TempoSlider.Value); 
            end
            
            app.Statuspercentlabel.Text = "100 %"; % 100 % prozent status
            app.Lamp.Color = [0 1 0]; % lampe grün
            app.Startrobot.Value = false;
        end

        % Button pushed function: Helpbutton_1, Helpbutton_2, 
        % Helpbutton_3, Helpbutton_4
        function help_callback(app, event)

            if event.Source.Parent.Parent.Tag == "Startwert" % button texte
                infotext(app,301); 
                
            elseif event.Source.Parent.Parent.Tag == "Zielwert" % button texte
                infotext(app,302); 
            
            elseif event.Source.Parent.Parent.Tag == "Runtime" % button texte
                infotext(app,303); 
                
            elseif event.Source.Parent.Parent.Tag == "Armsettings" % button texte
                infotext(app,304); 
            end
            
        end

        % Button pushed function: Reset
        function reset_callback(app, event)
            
            % setzt vorgebene standartwerte ein
            app.L1.Value = 0.3; % länge
            app.L2.Value = 0.4;
            app.Lc1.Value = 0.15; % abstand
            app.Lc2.Value = 0.2;
            app.m1.Value = 2; % gewicht
            app.m2.Value = 3;
            app.I1.Value = 0.02; % sensor
            app.I2.Value = 0.04;
            app.Kr1.Value = 1.5; % Reibung
            app.Kr2.Value = 1.5;
            app.G.Value = 9.81; % Fallbeschleunigung
            
        end

        % Button pushed function: Set
        function set_werte_callback(app, event)
            
            % setz ein paar globale variablen aufdie externe funktionen
            % zugreifen können
            global l1 l2 lc1 lc2 m1 m2 I1 I2 kr1 kr2 g;
            l1 = app.L1.Value; % länge
            l2 = app.L2.Value;
            lc1 = app.Lc1.Value; % abstand
            lc2 = app.Lc2.Value;
            m1 = app.m1.Value; % gewicht
            m2 = app.m2.Value;
            I1 = app.I1.Value; % sensor
            I2 = app.I2.Value;
            kr1 = app.Kr1.Value; % Reibung
            kr2 = app.Kr2.Value;
            g = app.G.Value; % Fallbeschleunigung
            
        end

        % Value changed function: Arbeitsbereich
        function drawrobo_callback(app, event)
            
            % prüft ob wert sich im arbeitsbereich befindet
            if fun_checkWorkspace(app.p_x.Value,app.p_y.Value) == 0
                infotext(app,201); % warnung
                return;
            else
                infotext(app,500); % info text
            end
            
            % zeichnet den roboterarm
            fun_drawRobo(app.Robotarm,app.theta_1.Value,app.theta_2.Value );

            % fügt ziel makierung hinzu
            hold(app.Robotarm, "on");
            plot(app.Robotarm,app.p_x_2.Value,app.p_y_2.Value,"g.",'MarkerSize',15)
            hold(app.Robotarm, "off");

            % fügt arbeitsbereich hinzu
            if app.Arbeitsbereich.Value == true
                arbeitsbereich(app,app.Robotarm);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HaubtGrid = uigridlayout(app.UIFigure);
            app.Hinweis = uipanel(app.HaubtGrid);
            app.Hinweis.Title = 'Hinweise';
            app.HinweisGrid = uigridlayout(app.Hinweis);
            app.InfoText = uilabel(app.HinweisGrid);
            app.InfoText.Text = '';
            app.ZielwertePanel = uipanel(app.HaubtGrid);
            app.ZielwertePanel.Title = 'Zielwerte (Regelung)';
            app.ZielwertePanel.Tag = 'Zielwert';
            app.GridLayout6_2 = uigridlayout(app.ZielwertePanel);
            app.Helpbutton_3 = uibutton(app.GridLayout6_2, 'push');
            app.Helpbutton_3.ButtonPushedFcn = createCallbackFcn(app, @help_callback, true);
            app.Helpbutton_3.Tooltip = {'hilfe'};
            app.Helpbutton_3.Text = '❔';
            app.ruckwaerts_2 = uibutton(app.GridLayout6_2, 'push');
            app.ruckwaerts_2.ButtonPushedFcn = createCallbackFcn(app, @rueckwaertskinematic_callback, true);
            app.ruckwaerts_2.Tooltip = {'wandelt x y werte in winkel'};
            app.ruckwaerts_2.Text = '◀';
            app.vorwaerts_2 = uibutton(app.GridLayout6_2, 'push');
            app.vorwaerts_2.ButtonPushedFcn = createCallbackFcn(app, @vorwaertskinematic_callback, true);
            app.vorwaerts_2.Tooltip = {'wandel winkel zu x y werten um'};
            app.vorwaerts_2.Text = '▶';
            app.infotext_2 = uilabel(app.GridLayout6_2);
            app.infotext_2.Text = 'Q = {-0.7,.,0.7} meter';
            app.infotext_1 = uilabel(app.GridLayout6_2);
            app.infotext_1.Text = 'Q = {-359,,359} grad';
            app.theta_1_2 = uieditfield(app.GridLayout6_2, 'numeric');
            app.theta_1_2.ValueDisplayFormat = '%.3f';
            app.theta_1EditField_4Label = uilabel(app.GridLayout6_2);
            app.theta_1EditField_4Label.Text = 'theta_1';
            app.theta_2_2 = uieditfield(app.GridLayout6_2, 'numeric');
            app.theta_2_2.ValueDisplayFormat = '%.3f';
            app.theta_2EditField_4Label = uilabel(app.GridLayout6_2);
            app.theta_2EditField_4Label.Text = 'theta_2';
            app.p_x_2 = uieditfield(app.GridLayout6_2, 'numeric');
            app.p_x_2.ValueDisplayFormat = '%.3f';
            app.p_xEditField_4Label = uilabel(app.GridLayout6_2);
            app.p_xEditField_4Label.Text = 'p_x';
            app.p_y_2 = uieditfield(app.GridLayout6_2, 'numeric');
            app.p_y_2.ValueDisplayFormat = '%.3f';
            app.p_yEditField_4Label = uilabel(app.GridLayout6_2);
            app.p_yEditField_4Label.Text = 'p_y';
            app.SettingsPanel = uitabgroup(app.HaubtGrid);
            app.Runtime = uitab(app.SettingsPanel);
            app.Runtime.Title = 'Runtime';
            app.Runtime.Tag = 'Runtime';
            app.RuntimeGrid = uigridlayout(app.Runtime);
            app.Helpbutton_2 = uibutton(app.RuntimeGrid, 'push');
            app.Helpbutton_2.ButtonPushedFcn = createCallbackFcn(app, @help_callback, true);
            app.Helpbutton_2.Tooltip = {'hilfe'};
            app.Helpbutton_2.Text = '❔';
            app.Statuspercentlabel = uilabel(app.RuntimeGrid);
            app.Statuspercentlabel.Text = '100%';
            app.Verfahren = uibutton(app.RuntimeGrid, 'push');
            app.Verfahren.ButtonPushedFcn = createCallbackFcn(app, @moverobo_callback, true);
            app.Verfahren.Tooltip = {'Da plannung vorrausgesetzt ist , führt der button diese sicherheitsalber auch aus.'};
            app.Verfahren.Text = 'Verfahre';
            app.Planung = uibutton(app.RuntimeGrid, 'push');
            app.Planung.ButtonPushedFcn = createCallbackFcn(app, @plannung_callback, true);
            app.Planung.Tooltip = {'Nur werte in P_x P_y start und ziel werden beachtet:'};
            app.Planung.Text = 'Planung';
            app.Vefahrensgruppe = uibuttongroup(app.RuntimeGrid);
            app.Vefahrensgruppe.Title = 'Verfahren';
            app.Analytisch = uiradiobutton(app.Vefahrensgruppe);
            app.Analytisch.Text = 'Analytisch';
            app.Analytisch.Value = true;
            app.Nummerisch = uiradiobutton(app.Vefahrensgruppe);
            app.Nummerisch.Text = 'Nummerisch';
            app.Lamp = uilamp(app.RuntimeGrid);
            app.Startrobot = uibutton(app.RuntimeGrid, 'state');
            app.Startrobot.ValueChangedFcn = createCallbackFcn(app, @start_bewegung_callback, true);
            app.Startrobot.Tooltip = {'bewegt model nach der verfahrensberechnung'};
            app.Startrobot.Text = 'starte bewegung';
            app.Clear = uibutton(app.RuntimeGrid, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @clear_callback, true);
            app.Clear.Tooltip = {'Leert alle felder'};
            app.Clear.Text = {'Clear all'; ''};
            app.TempoSlider = uislider(app.RuntimeGrid);
            app.TempoSlider.Limits = [1 50];
            app.TempoSlider.Tooltip = {'stellt das tempo der bewegung da , empfohlen ist eine höhere zu nehmen weil es sonst lange dauern könnte'};
            app.TempoSlider.Value = 1;
            app.Tempolabel = uilabel(app.RuntimeGrid);
            app.Tempolabel.Text = 'Tempo';
            app.Arbeitsbereich = uibutton(app.RuntimeGrid, 'state');
            app.Arbeitsbereich.ValueChangedFcn = createCallbackFcn(app, @drawrobo_callback, true);
            app.Arbeitsbereich.Tooltip = {'Zeigt den nutzbaren arbeitsbereich'};
            app.Arbeitsbereich.Text = 'Arbeitsbereich';
            app.Armsettings = uitab(app.SettingsPanel);
            app.Armsettings.Title = 'Arm-settings';
            app.Armsettings.Tag = 'Armsettings';
            app.ArmsettingGrid = uigridlayout(app.Armsettings);
            app.Helpbutton_4 = uibutton(app.ArmsettingGrid, 'push');
            app.Helpbutton_4.ButtonPushedFcn = createCallbackFcn(app, @help_callback, true);
            app.Helpbutton_4.Tooltip = {'hilfe'};
            app.Helpbutton_4.Text = '❔';
            app.Reset = uibutton(app.ArmsettingGrid, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @reset_callback, true);
            app.Reset.Tooltip = {'packt standart werte in die felder'};
            app.Reset.Text = 'Reset';
            app.Set = uibutton(app.ArmsettingGrid, 'push');
            app.Set.ButtonPushedFcn = createCallbackFcn(app, @set_werte_callback, true);
            app.Set.Tooltip = {'Speicher werte'};
            app.Set.Text = 'Set';
            app.Kr1Label = uilabel(app.ArmsettingGrid);
            app.Kr1Label.Text = 'Kr1';
            app.Kr1 = uieditfield(app.ArmsettingGrid, 'numeric');
            app.Kr1.ValueDisplayFormat = '%.3f';
            app.Lc1Label = uilabel(app.ArmsettingGrid);
            app.Lc1Label.Text = {'Lc1'; ''};
            app.Lc1 = uieditfield(app.ArmsettingGrid, 'numeric');
            app.Lc1.ValueDisplayFormat = '%.3f';
            app.GLabel = uilabel(app.ArmsettingGrid);
            app.GLabel.Text = 'G';
            app.G = uieditfield(app.ArmsettingGrid, 'numeric');
            app.G.ValueDisplayFormat = '%.3f';
            app.L1Label = uilabel(app.ArmsettingGrid);
            app.L1Label.Text = 'L1';
            app.L1 = uieditfield(app.ArmsettingGrid, 'numeric');
            app.L1.ValueDisplayFormat = '%.3f';
            app.Kr2 = uieditfield(app.ArmsettingGrid, 'numeric');
            app.Kr2.ValueDisplayFormat = '%.3f';
            app.Kr2Label = uilabel(app.ArmsettingGrid);
            app.Kr2Label.Text = 'Kr2';
            app.I1 = uieditfield(app.ArmsettingGrid, 'numeric');
            app.I1.ValueDisplayFormat = '%.3f';
            app.I1Label = uilabel(app.ArmsettingGrid);
            app.I1Label.Text = 'I1';
            app.I2 = uieditfield(app.ArmsettingGrid, 'numeric');
            app.I2.ValueDisplayFormat = '%.3f';
            app.I2Label = uilabel(app.ArmsettingGrid);
            app.I2Label.Text = 'I2';
            app.Lc2 = uieditfield(app.ArmsettingGrid, 'numeric');
            app.Lc2.ValueDisplayFormat = '%.3f';
            app.Lc2Label = uilabel(app.ArmsettingGrid);
            app.Lc2Label.Text = 'Lc2';
            app.L2 = uieditfield(app.ArmsettingGrid, 'numeric');
            app.L2.ValueDisplayFormat = '%.3f';
            app.L2Label = uilabel(app.ArmsettingGrid);
            app.L2Label.Text = 'L2';
            app.m2 = uieditfield(app.ArmsettingGrid, 'numeric');
            app.m2.ValueDisplayFormat = '%.3f';
            app.m2Label = uilabel(app.ArmsettingGrid);
            app.m2Label.Text = 'm2';
            app.m1 = uieditfield(app.ArmsettingGrid, 'numeric');
            app.m1.ValueDisplayFormat = '%.3f';
            app.m1Label = uilabel(app.ArmsettingGrid);
            app.m1Label.Text = 'm1';
            app.StartwerPanel = uipanel(app.HaubtGrid);
            app.StartwerPanel.Title = 'Startwerte (Kinematic)';
            app.StartwerPanel.Tag = 'Startwert';
            app.StartwertGrid = uigridlayout(app.StartwerPanel);
            app.Helpbutton_1 = uibutton(app.StartwertGrid, 'push');
            app.Helpbutton_1.ButtonPushedFcn = createCallbackFcn(app, @help_callback, true);
            app.Helpbutton_1.Tooltip = {'hilfe'};
            app.Helpbutton_1.Text = '❔';
            app.ruckwaerts = uibutton(app.StartwertGrid, 'push');
            app.ruckwaerts.ButtonPushedFcn = createCallbackFcn(app, @rueckwaertskinematic_callback, true);
            app.ruckwaerts.Tooltip = {'wandelt x y werte in winkel'};
            app.ruckwaerts.Text = '◀';
            app.vorwaerts = uibutton(app.StartwertGrid, 'push');
            app.vorwaerts.ButtonPushedFcn = createCallbackFcn(app, @vorwaertskinematic_callback, true);
            app.vorwaerts.Tooltip = {'wandel winkel zu x y werten um'};
            app.vorwaerts.Text = '▶';
            app.infotext_5 = uilabel(app.StartwertGrid);
            app.infotext_5.Text = 'Q = {-0.7,.,0.7} meter';
            app.infotext_6 = uilabel(app.StartwertGrid);
            app.infotext_6.Text = 'Q = {-359,,359} grad';
            app.theta_1 = uieditfield(app.StartwertGrid, 'numeric');
            app.theta_1.ValueDisplayFormat = '%.3f';
            app.theta_1EditField_4Label_2 = uilabel(app.StartwertGrid);
            app.theta_1EditField_4Label_2.Text = 'theta_1';
            app.theta_2 = uieditfield(app.StartwertGrid, 'numeric');
            app.theta_2.ValueDisplayFormat = '%.3f';
            app.theta_2EditField_4Label_2 = uilabel(app.StartwertGrid);
            app.theta_2EditField_4Label_2.Text = 'theta_2';
            app.p_x = uieditfield(app.StartwertGrid, 'numeric');
            app.p_x.ValueDisplayFormat = '%.3f';
            app.p_xEditField_4Label_2 = uilabel(app.StartwertGrid);
            app.p_xEditField_4Label_2.Text = 'p_x';
            app.p_y = uieditfield(app.StartwertGrid, 'numeric');
            app.p_y.ValueDisplayFormat = '%.3f';
            app.p_yEditField_4Label_2 = uilabel(app.StartwertGrid);
            app.p_yEditField_4Label_2.Text = 'p_y';
            app.Anzeige = uipanel(app.HaubtGrid);
            app.Anzeige.Title = 'Panel';
            app.AnzeigenGrid = uigridlayout(app.Anzeige);
            app.theta2verlauf = uiaxes(app.AnzeigenGrid);
            title(app.theta2verlauf, 'Theta2')
            xlabel(app.theta2verlauf, 'Time')
            ylabel(app.theta2verlauf, 'Winkel')
            zlabel(app.theta2verlauf, 'Z')
            app.theta1verlauf = uiaxes(app.AnzeigenGrid);
            title(app.theta1verlauf, 'Theta1')
            xlabel(app.theta1verlauf, 'Time')
            ylabel(app.theta1verlauf, 'Winkel')
            zlabel(app.theta1verlauf, 'Z')
            app.Robotarm = uiaxes(app.AnzeigenGrid);
            title(app.Robotarm, {'Roboarme'; ''})
            xlabel(app.Robotarm, 'X')
            ylabel(app.Robotarm, 'Y')
            zlabel(app.Robotarm, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Robo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end